classdef Lab4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        PlikMenu               matlab.ui.container.Menu
        WczytajMenu            matlab.ui.container.Menu
        ZapiszMenu             matlab.ui.container.Menu
        KoniecMenu             matlab.ui.container.Menu
        Wczytajobraz1Menu      matlab.ui.container.Menu
        Wczytajobraz2Menu      matlab.ui.container.Menu
        EdycjaMenu             matlab.ui.container.Menu
        RGBdoGrayMenu          matlab.ui.container.Menu
        GraytoRGBMenu          matlab.ui.container.Menu
        ShowRGB1Menu           matlab.ui.container.Menu
        ShowRGB2Menu           matlab.ui.container.Menu
        FiltryMenu             matlab.ui.container.Menu
        AverageMenu            matlab.ui.container.Menu
        DiscMenu               matlab.ui.container.Menu
        LaplacianMenu          matlab.ui.container.Menu
        LogMenu                matlab.ui.container.Menu
        MotionMenu             matlab.ui.container.Menu
        PrewittMenu            matlab.ui.container.Menu
        SobelMenu              matlab.ui.container.Menu
        KrawedzieMenu          matlab.ui.container.Menu
        Poziompodstawowy1Menu  matlab.ui.container.Menu
        Poziomwzmocniony1Menu  matlab.ui.container.Menu
        Poziompodstawowy2Menu  matlab.ui.container.Menu
        Poziomwzmocniony2Menu  matlab.ui.container.Menu
        Pionpodstawowy1Menu    matlab.ui.container.Menu
        Pionwzmocniony1Menu    matlab.ui.container.Menu
        Pionpodstawowy2Menu    matlab.ui.container.Menu
        Pionwzmocniony2Menu    matlab.ui.container.Menu
        KierunkowyMenu         matlab.ui.container.Menu
        Lab89Menu              matlab.ui.container.Menu
        FASTMenu               matlab.ui.container.Menu
        HarrisMenu             matlab.ui.container.Menu
        KAZEMenu               matlab.ui.container.Menu
        MSERMenu               matlab.ui.container.Menu
        Lab1011Menu            matlab.ui.container.Menu
        KluczowanieMenu        matlab.ui.container.Menu
        LiczbaskadowychLabel   matlab.ui.control.Label
        YLabel                 matlab.ui.control.Label
        XLabel                 matlab.ui.control.Label
        JasnoscLabel           matlab.ui.control.Label
        SliderLigth            matlab.ui.control.Slider
        RedLabel               matlab.ui.control.Label
        SliderR                matlab.ui.control.Slider
        GreenLabel             matlab.ui.control.Label
        SliderG                matlab.ui.control.Slider
        BlueLabel              matlab.ui.control.Label
        SliderB                matlab.ui.control.Slider
        ImagefuseSliderLabel   matlab.ui.control.Label
        ImagefuseSlider        matlab.ui.control.Slider
        ProgowanieSliderLabel  matlab.ui.control.Label
        ProgowanieSlider       matlab.ui.control.Slider
        LiczbakrawedziLabel    matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
        UIAxes2                matlab.ui.control.UIAxes
        UIAxesR                matlab.ui.control.UIAxes
        UIAxesG                matlab.ui.control.UIAxes
        UIAxesB                matlab.ui.control.UIAxes
        UIAxes3                matlab.ui.control.UIAxes
        UIAxes4                matlab.ui.control.UIAxes
        UIAxes5                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: KoniecMenu
        function KoniecMenuSelected(app, event)
            exit();
        end

        % Menu selected function: WczytajMenu
        function WczytajMenuSelected(app, event)
            global obraz obraz_kopia;
            [plik,sciezka]=uigetfile('*.jpg;*.png;*.bmp','Wczytaj obraz');
            obraz=imread([sciezka,plik]);
            obraz_kopia=obraz;
            [x,y,z]=size(obraz);
            set(app.XLabel,'Text',['X: ',num2str(x)]);
            set(app.YLabel,'Text',['Y: ',num2str(y)]);
            set(app.LiczbaskadowychLabel,'Text',['Liczba składowych: ',num2str(z)]);
            %set(app.UIAxes,'Position',[1,384,y,x]);
            imshow(obraz,'Parent',app.UIAxes);
        end

        % Menu selected function: RGBdoGrayMenu
        function RGBdoGrayMenuSelected(app, event)
            global obraz;
            obraz=rgb2gray(obraz);
            imshow(obraz,'Parent',app.UIAxes2);
        end

        % Menu selected function: GraytoRGBMenu
        function GraytoRGBMenuSelected(app, event)
            global obraz obraz_kopia;
            obraz=obraz_kopia;
            imshow(obraz,'Parent',app.UIAxes2);
        end

        % Menu selected function: ZapiszMenu
        function ZapiszMenuSelected(app, event)
            global obraz
            [plik,sciezka]=uiputfile('*.jpg','Zapisz obraz');
            imwrite(obraz,[sciezka,plik]);
        end

        % Menu selected function: ShowRGB1Menu
        function ShowRGB1MenuSelected(app, event)
            global obraz;
            imshow(obraz(:,:,1),'Parent',app.UIAxesR);
            imshow(obraz(:,:,2),'Parent',app.UIAxesG);
            imshow(obraz(:,:,3),'Parent',app.UIAxesB);
        end

        % Menu selected function: ShowRGB2Menu
        function ShowRGB2MenuSelected(app, event)
            global obraz r_value g_value b_value;
            r_value = 1;
            g_value = 1;
            b_value = 1;
            %set(app.SliderR,'Value',r_value);
            obraz_R=obraz;
            obraz_R(:,:,2)=0;
            obraz_R(:,:,3)=0;
            imshow(obraz_R,'Parent',app.UIAxesR);
            obraz_G=obraz;
            obraz_G(:,:,1)=0;
            obraz_G(:,:,3)=0;
            imshow(obraz_G,'Parent',app.UIAxesG);
            obraz_B=obraz;
            obraz_B(:,:,1)=0;
            obraz_B(:,:,2)=0;
            imshow(obraz_B,'Parent',app.UIAxesB);
        end

        % Value changed function: SliderLigth
        function SliderLigthValueChanged(app, event)
           global obraz_kopia obraz_new;
           changedValue = event.Value;
           obraz_new = obraz_kopia;
           obraz_new = obraz_new*changedValue;
           imshow(obraz_new,'Parent',app.UIAxes2);
        end

        % Value changing function: SliderLigth
        function SliderLigthValueChanging(app, event)
            global obraz_kopia obraz_new;
            changingValue = event.Value;
            obraz_new=obraz_kopia;
            obraz_new=obraz_new*changingValue;    
            imshow(obraz_new,'Parent',app.UIAxes);
            obraz_new_gray=rgb2gray(obraz_new);
            imshow(obraz_new_gray,'Parent',app.UIAxes2);       
        end

        % Value changed function: SliderR
        function SliderRValueChanged(app, event)
            value = app.SliderR.Value;
            global obraz_kopia obraz_new;
            obraz_new=obraz_kopia;
            obraz_kopia(:,:,1)=obraz_kopia(:,:,1)*value;
            imshow(obraz_kopia,'Parent',app.UIAxes2);
        end

        % Value changed function: SliderG
        function SliderGValueChanged(app, event)
            value = app.SliderR.Value;
            global obraz_kopia obraz_new;
            obraz_new=obraz_kopia;
            obraz_kopia(:,:,2)=obraz_kopia(:,:,2)*value;
            imshow(obraz_kopia,'Parent',app.UIAxes2);
        end

        % Value changed function: SliderB
        function SliderBValueChanged(app, event)
            value = app.SliderR.Value;
            global obraz_kopia obraz_new;
            obraz_new=obraz_kopia;
            obraz_kopia(:,:,3)=obraz_kopia(:,:,value)+value;
            imshow(obraz_kopia,'Parent',app.UIAxes2);
        end

        % Value changing function: SliderB
        function SliderBValueChanging(app, event)
            value = app.SliderR.Value;
            global obraz_kopia obraz_new;
            obraz_new=obraz_kopia;
            obraz_kopia(:,:,3)=obraz_kopia(:,:,3)+value;
            imshow(obraz_kopia,'Parent',app.UIAxes2);
        end

        % Value changing function: SliderG
        function SliderGValueChanging(app, event)
            value = app.SliderR.Value;
            global obraz_kopia obraz_new;
            obraz_new=obraz_kopia;
            obraz_kopia(:,:,2)=obraz_kopia(:,:,2)+value;
            imshow(obraz_kopia,'Parent',app.UIAxes2);
        end

        % Value changing function: SliderR
        function SliderRValueChanging(app, event)
            value = app.SliderR.Value;
            global obraz_kopia obraz_new;
            obraz_new=obraz_kopia;
            obraz_kopia(:,:,1)=obraz_kopia(:,:,1)+value;
            imshow(obraz_kopia,'Parent',app.UIAxes2);
        end

        % Menu selected function: Wczytajobraz1Menu
        function Wczytajobraz1MenuSelected(app, event)
            global obraz1 obraz_kopia1;
            [plik,sciezka]=uigetfile('*.jpg;*.png;*.bmp','Wczytaj obraz');
            obraz1=imread([sciezka,plik]);
            obraz_kopia1=obraz1;
            obraz1=imresize(obraz1,[480,640]);
            imshow(obraz_kopia1,'Parent',app.UIAxes3);
        end

        % Menu selected function: Wczytajobraz2Menu
        function Wczytajobraz2MenuSelected(app, event)
            global obraz2 obraz_kopia2;
            [plik,sciezka]=uigetfile('*.jpg;*.png;*.bmp','Wczytaj obraz');
            obraz2=imread([sciezka,plik]);
            obraz_kopia2=obraz2;
            obraz2=imresize(obraz2,[480,640]);
            imshow(obraz_kopia2,'Parent',app.UIAxes4);
        end

        % Value changing function: ImagefuseSlider
        function ImagefuseSliderValueChanging(app, event)
            global obraz1 obraz2
            changingValue = event.Value;
            obraz_fin = obraz1 * (1-changingValue) + obraz2 * changingValue;
            imshow(obraz_fin,'Parent',app.UIAxes5);
        end

        % Menu selected function: AverageMenu
        function AverageMenuSelected(app, event)
            global obraz obraz1
            maska=fspecial('average',6);
            obraz1=imfilter(obraz,maska);
            imshow(obraz1,'Parent',app.UIAxes2);
        end

        % Menu selected function: DiscMenu
        function DiscMenuSelected(app, event)
            global obraz obraz1
            maska=fspecial('disk',10);
            obraz1=imfilter(obraz,maska);
            imshow(obraz1,'Parent',app.UIAxes2);
        end

        % Menu selected function: LaplacianMenu
        function LaplacianMenuSelected(app, event)
            global obraz obraz1
            maska=fspecial('laplacian',0.6);
            obraz1=imfilter(obraz,maska);
            imshow(obraz1,'Parent',app.UIAxes2);
        end

        % Menu selected function: LogMenu
        function LogMenuSelected(app, event)
            global obraz obraz1
            maska=fspecial('log',5,0.9);
            obraz1=imfilter(obraz,maska);
            imshow(obraz1,'Parent',app.UIAxes2);
        end

        % Menu selected function: MotionMenu
        function MotionMenuSelected(app, event)
            global obraz obraz1
            maska=fspecial('motion',3,5);
            obraz1=imfilter(obraz,maska);
            imshow(obraz1,'Parent',app.UIAxes2);
        end

        % Menu selected function: PrewittMenu
        function PrewittMenuSelected(app, event)
            global obraz obraz1
            maska=fspecial('prewitt');
            obraz1=imfilter(obraz,maska);
            imshow(obraz1,'Parent',app.UIAxes2);
        end

        % Menu selected function: SobelMenu
        function SobelMenuSelected(app, event)
            global obraz obraz1
            maska=fspecial('sobel');
            obraz1=imfilter(obraz,maska);
            imshow(obraz1,'Parent',app.UIAxes2);
        end

        % Callback function
        function ProgowanieSliderValueChanging(app, event)
            changingValue = event.Value;
            global obraz1
            obraz2=obraz1;
            obraz2(obraz1 < changingValue) = 10;
            imshow(obraz2,'Parent',app.UIAxes2);
        end

        % Value changing function: ProgowanieSlider
        function ProgowanieSliderValueChanging2(app, event)
            changingValue = event.Value;
            global obraz1
            obraz2 = obraz1;
            obraz_grey=rgb2gray(obraz1);
            obraz2(obraz_grey < changingValue) = 10;
            imshow(obraz2,'Parent',app.UIAxes2);
        end

        % Menu selected function: Poziompodstawowy1Menu
        function Poziompodstawowy1MenuSelected(app, event)
            global obraz
            obraz2 = obraz;
            obraz2 = im2gray(obraz2);
            maska_poziom1 = [1,1,1;0,0,0;-1,-1,-1];
            wynik = imfilter(obraz2,maska_poziom1);
            prog = 0.1;
            liczba_krawedzi = sum(wynik(:) > prog);
            set(app.LiczbakrawedziLabel,'Text',['Liczba krawedzi: ',num2str(liczba_krawedzi)]);
            imshow(wynik,"Parent",app.UIAxes2)
            
        end

        % Menu selected function: Poziomwzmocniony1Menu
        function Poziomwzmocniony1MenuSelected(app, event)
            global obraz
            obraz2 = obraz;
            obraz2 = im2gray(obraz2);
            maska_poziom2 = [2,2,2;0,0,0;-2,-2,-2];
            wynik = imfilter(obraz2,maska_poziom2);
            prog = 0.1;
            liczba_krawedzi = sum(wynik(:) > prog);
            set(app.LiczbakrawedziLabel,'Text',['Liczba krawedzi: ',num2str(liczba_krawedzi)]);
            imshow(wynik,"Parent",app.UIAxes2)
        end

        % Menu selected function: Poziompodstawowy2Menu
        function Poziompodstawowy2MenuSelected(app, event)
            global obraz
            obraz2 = obraz;
            obraz2 = im2gray(obraz2);
            maska_poziom3 = [1,2,1;0,0,0;-1,-2,-1];
            wynik = imfilter(obraz2,maska_poziom3);
            prog = 0.1;
            liczba_krawedzi = sum(wynik(:) > prog);
            set(app.LiczbakrawedziLabel,'Text',['Liczba krawedzi: ',num2str(liczba_krawedzi)]);
            imshow(wynik,"Parent",app.UIAxes2)
        end

        % Menu selected function: Poziomwzmocniony2Menu
        function Poziomwzmocniony2MenuSelected(app, event)
            global obraz
            obraz2 = obraz;
            obraz2 = im2gray(obraz2);
            maska_poziom4 = [3,4,3;0,0,0;-3,-4,-3];
            wynik = imfilter(obraz2,maska_poziom4);
            prog = 0.1;
            liczba_krawedzi = sum(wynik(:) > prog);
            set(app.LiczbakrawedziLabel,'Text',['Liczba krawedzi: ',num2str(liczba_krawedzi)]);
            imshow(wynik,"Parent",app.UIAxes2)
        end

        % Menu selected function: Pionpodstawowy1Menu
        function Pionpodstawowy1MenuSelected(app, event)
            global obraz
            obraz2 = obraz;
            obraz2 = im2gray(obraz2);
            maska_pion1 = [1,0,-1;1,0,-1;1,0,-1];
            wynik = imfilter(obraz2,maska_pion1);
            prog = 0.1;
            liczba_krawedzi = sum(wynik(:) > prog);
            set(app.LiczbakrawedziLabel,'Text',['Liczba krawedzi: ',num2str(liczba_krawedzi)]);
            imshow(wynik,"Parent",app.UIAxes2)
        end

        % Menu selected function: Pionwzmocniony1Menu
        function Pionwzmocniony1MenuSelected(app, event)
            global obraz
            obraz2 = obraz;
            obraz2 = im2gray(obraz2);
            maska_pion2 = [2,0,-2;2,0,-2;2,0,-2];
            wynik = imfilter(obraz2,maska_pion2);
            prog = 0.1;
            liczba_krawedzi = sum(wynik(:) > prog);
            set(app.LiczbakrawedziLabel,'Text',['Liczba krawedzi: ',num2str(liczba_krawedzi)]);
            imshow(wynik,"Parent",app.UIAxes2)
        end

        % Menu selected function: Pionpodstawowy2Menu
        function Pionpodstawowy2MenuSelected(app, event)
            global obraz
            obraz2 = obraz;
            obraz2 = im2gray(obraz2);
            maska_poion3 = [-1,0,1;-1,0,1;-1,0,1];
            wynik = imfilter(obraz2,maska_poion3);
            prog = 0.1;
            liczba_krawedzi = sum(wynik(:) > prog);
            set(app.LiczbakrawedziLabel,'Text',['Liczba krawedzi: ',num2str(liczba_krawedzi)]);
            imshow(wynik,"Parent",app.UIAxes2)            
        end

        % Menu selected function: Pionwzmocniony2Menu
        function Pionwzmocniony2MenuSelected(app, event)
            global obraz
            obraz2 = obraz;
            obraz2 = im2gray(obraz2);
            maska_poion4 = [-2,0,2;-2,0,2;-2,0,2];
            wynik = imfilter(obraz2,maska_poion4);
            prog = 0.1;
            liczba_krawedzi = sum(wynik(:) > prog);
            set(app.LiczbakrawedziLabel,'Text',['Liczba krawedzi: ',num2str(liczba_krawedzi)]);
            imshow(wynik,"Parent",app.UIAxes2)
        end

        % Menu selected function: KierunkowyMenu
        function KierunkowyMenuSelected(app, event)
            global obraz maska_poziom2 maska_poion2
            obraz2 = obraz;
            obraz2 = im2gray(obraz2);
            obraz3 = obraz2;
            maska_skosna1 = [-3,2,0;2,0,-2;0,-2,-3];
            maska_skosna2 = [0,-2,-3;2,0,-2;3,2,0];
            %obraz3=imfilter(obraz3,maska_skosna1);
            %obraz2=imfilter(obraz2,maska_skosna2);
            %wynik = imfuse(obraz3,obraz2);
            ob1 = imfilter(obraz3,maska_poziom2);
            %ob2 = imfilter(obraz3,maska_poziom4);
            ob3 = imfilter(obraz3,maska_poion2);
            %ob4 = imfilter(obraz3,maska_poion4);
            ob5 = imfilter(obraz3,maska_skosna1);
            ob6 = imfilter(obraz2,maska_skosna2);
            wynik = ob1 + ob3 + ob5 + ob6;
            prog = 0.1;
            liczba_krawedzi = sum(wynik(:) > prog);
            set(app.LiczbakrawedziLabel,'Text',['Liczba krawedzi: ',num2str(liczba_krawedzi)]);
            imshow(wynik,"Parent",app.UIAxes2)
        end

        % Menu selected function: FASTMenu
        function FASTMenuSelected(app, event)
            global obraz
            grayImg = rgb2gray(obraz);
            FAST = detectFASTFeatures(grayImg);
            figure; 
            imshow(obraz); hold on;
            plot(FAST.selectStrongest(100));
            hold off;
        end

        % Menu selected function: HarrisMenu
        function HarrisMenuSelected(app, event)
            global obraz
            grayImg = rgb2gray(obraz);
            HARRIS = detectHarrisFeatures(grayImg);
            figure; 
            imshow(obraz); hold on;
            plot(HARRIS.selectStrongest(100));
            hold off;
        end

        % Menu selected function: KAZEMenu
        function KAZEMenuSelected(app, event)
            global obraz
            grayImg = rgb2gray(obraz);
            KAZE = detectKAZEFeatures(grayImg);
            figure;
            imshow(obraz);  hold on;
            plot(KAZE.selectStrongest(50));
            hold off;
        end

        % Menu selected function: MSERMenu
        function MSERMenuSelected(app, event)
            global obraz
            grayImg = rgb2gray(obraz);
            MSER = detectMSERFeatures(grayImg);
            figure; 
            imshow(obraz); hold on;
            plot(MSER,'showEllipses', false);
            hold off;
        end

        % Menu selected function: KluczowanieMenu
        function KluczowanieMenuSelected(app, event)
            global obraz1 obraz2
            tmp =impixel(obraz1);    
            max_r = max(tmp(:,1));
            min_r = min(tmp(:,1));
            max_g = max(tmp(:,2));
            min_g = min(tmp(:,2));
            max_b = max(tmp(:,3));
            min_b = min(tmp(:,3));
            prog_R = 20;
            prog_G = 20;
            prog_B = 20;
            mask = (obraz1(:,:,1) >= (min_r - prog_R)) & (obraz1(:,:,1) <= (max_r + prog_R)) & ... 
                   (obraz1(:,:,2) >= (min_g - prog_G)) & (obraz1(:,:,2) <= (max_g + prog_G)) & ... 
                   (obraz1(:,:,3) >= (min_b - prog_B)) & (obraz1(:,:,3) <= (max_b + prog_B));
            obraz1(repmat(mask, [1, 1, 3])) = 0;
            obraz2 = imresize(obraz2, [size(obraz1, 1), size(obraz1, 2)]);
            obraz1(repmat(mask, [1, 1, 3])) = obraz2(repmat(mask, [1, 1, 3]));
            imshow(obraz1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlikMenu = uimenu(app.UIFigure);
            app.PlikMenu.Text = 'Plik';
            app.WczytajMenu = uimenu(app.PlikMenu);
            app.WczytajMenu.MenuSelectedFcn = createCallbackFcn(app, @WczytajMenuSelected, true);
            app.WczytajMenu.Text = 'Wczytaj';
            app.ZapiszMenu = uimenu(app.PlikMenu);
            app.ZapiszMenu.MenuSelectedFcn = createCallbackFcn(app, @ZapiszMenuSelected, true);
            app.ZapiszMenu.Text = 'Zapisz';
            app.KoniecMenu = uimenu(app.PlikMenu);
            app.KoniecMenu.MenuSelectedFcn = createCallbackFcn(app, @KoniecMenuSelected, true);
            app.KoniecMenu.Text = 'Koniec';
            app.Wczytajobraz1Menu = uimenu(app.PlikMenu);
            app.Wczytajobraz1Menu.MenuSelectedFcn = createCallbackFcn(app, @Wczytajobraz1MenuSelected, true);
            app.Wczytajobraz1Menu.Text = 'Wczytaj obraz 1';
            app.Wczytajobraz2Menu = uimenu(app.PlikMenu);
            app.Wczytajobraz2Menu.MenuSelectedFcn = createCallbackFcn(app, @Wczytajobraz2MenuSelected, true);
            app.Wczytajobraz2Menu.Text = 'Wczytaj obraz 2';
            app.EdycjaMenu = uimenu(app.UIFigure);
            app.EdycjaMenu.Text = 'Edycja';
            app.RGBdoGrayMenu = uimenu(app.EdycjaMenu);
            app.RGBdoGrayMenu.MenuSelectedFcn = createCallbackFcn(app, @RGBdoGrayMenuSelected, true);
            app.RGBdoGrayMenu.Text = 'RGBdoGray';
            app.GraytoRGBMenu = uimenu(app.EdycjaMenu);
            app.GraytoRGBMenu.MenuSelectedFcn = createCallbackFcn(app, @GraytoRGBMenuSelected, true);
            app.GraytoRGBMenu.Text = 'GraytoRGB';
            app.ShowRGB1Menu = uimenu(app.EdycjaMenu);
            app.ShowRGB1Menu.MenuSelectedFcn = createCallbackFcn(app, @ShowRGB1MenuSelected, true);
            app.ShowRGB1Menu.Text = 'ShowRGB1';
            app.ShowRGB2Menu = uimenu(app.EdycjaMenu);
            app.ShowRGB2Menu.MenuSelectedFcn = createCallbackFcn(app, @ShowRGB2MenuSelected, true);
            app.ShowRGB2Menu.Text = 'ShowRGB2';
            app.FiltryMenu = uimenu(app.UIFigure);
            app.FiltryMenu.Text = 'Filtry';
            app.AverageMenu = uimenu(app.FiltryMenu);
            app.AverageMenu.MenuSelectedFcn = createCallbackFcn(app, @AverageMenuSelected, true);
            app.AverageMenu.Text = 'Average';
            app.DiscMenu = uimenu(app.FiltryMenu);
            app.DiscMenu.MenuSelectedFcn = createCallbackFcn(app, @DiscMenuSelected, true);
            app.DiscMenu.Text = 'Disc';
            app.LaplacianMenu = uimenu(app.FiltryMenu);
            app.LaplacianMenu.MenuSelectedFcn = createCallbackFcn(app, @LaplacianMenuSelected, true);
            app.LaplacianMenu.Text = 'Laplacian';
            app.LogMenu = uimenu(app.FiltryMenu);
            app.LogMenu.MenuSelectedFcn = createCallbackFcn(app, @LogMenuSelected, true);
            app.LogMenu.Text = 'Log';
            app.MotionMenu = uimenu(app.FiltryMenu);
            app.MotionMenu.MenuSelectedFcn = createCallbackFcn(app, @MotionMenuSelected, true);
            app.MotionMenu.Text = 'Motion';
            app.PrewittMenu = uimenu(app.FiltryMenu);
            app.PrewittMenu.MenuSelectedFcn = createCallbackFcn(app, @PrewittMenuSelected, true);
            app.PrewittMenu.Text = 'Prewitt';
            app.SobelMenu = uimenu(app.FiltryMenu);
            app.SobelMenu.MenuSelectedFcn = createCallbackFcn(app, @SobelMenuSelected, true);
            app.SobelMenu.Text = 'Sobel';
            app.KrawedzieMenu = uimenu(app.UIFigure);
            app.KrawedzieMenu.Text = 'Krawedzie';
            app.Poziompodstawowy1Menu = uimenu(app.KrawedzieMenu);
            app.Poziompodstawowy1Menu.MenuSelectedFcn = createCallbackFcn(app, @Poziompodstawowy1MenuSelected, true);
            app.Poziompodstawowy1Menu.Text = 'Poziom podstawowy 1';
            app.Poziomwzmocniony1Menu = uimenu(app.KrawedzieMenu);
            app.Poziomwzmocniony1Menu.MenuSelectedFcn = createCallbackFcn(app, @Poziomwzmocniony1MenuSelected, true);
            app.Poziomwzmocniony1Menu.Text = 'Poziom wzmocniony 1';
            app.Poziompodstawowy2Menu = uimenu(app.KrawedzieMenu);
            app.Poziompodstawowy2Menu.MenuSelectedFcn = createCallbackFcn(app, @Poziompodstawowy2MenuSelected, true);
            app.Poziompodstawowy2Menu.Text = 'Poziom podstawowy 2';
            app.Poziomwzmocniony2Menu = uimenu(app.KrawedzieMenu);
            app.Poziomwzmocniony2Menu.MenuSelectedFcn = createCallbackFcn(app, @Poziomwzmocniony2MenuSelected, true);
            app.Poziomwzmocniony2Menu.Text = 'Poziom wzmocniony 2';
            app.Pionpodstawowy1Menu = uimenu(app.KrawedzieMenu);
            app.Pionpodstawowy1Menu.MenuSelectedFcn = createCallbackFcn(app, @Pionpodstawowy1MenuSelected, true);
            app.Pionpodstawowy1Menu.Text = 'Pion podstawowy 1';
            app.Pionwzmocniony1Menu = uimenu(app.KrawedzieMenu);
            app.Pionwzmocniony1Menu.MenuSelectedFcn = createCallbackFcn(app, @Pionwzmocniony1MenuSelected, true);
            app.Pionwzmocniony1Menu.Text = 'Pion wzmocniony 1';
            app.Pionpodstawowy2Menu = uimenu(app.KrawedzieMenu);
            app.Pionpodstawowy2Menu.MenuSelectedFcn = createCallbackFcn(app, @Pionpodstawowy2MenuSelected, true);
            app.Pionpodstawowy2Menu.Text = 'Pion podstawowy 2';
            app.Pionwzmocniony2Menu = uimenu(app.KrawedzieMenu);
            app.Pionwzmocniony2Menu.MenuSelectedFcn = createCallbackFcn(app, @Pionwzmocniony2MenuSelected, true);
            app.Pionwzmocniony2Menu.Text = 'Pion wzmocniony 2';
            app.KierunkowyMenu = uimenu(app.KrawedzieMenu);
            app.KierunkowyMenu.MenuSelectedFcn = createCallbackFcn(app, @KierunkowyMenuSelected, true);
            app.KierunkowyMenu.Text = 'Kierunkowy';
            app.Lab89Menu = uimenu(app.UIFigure);
            app.Lab89Menu.Text = 'Lab8/9';
            app.FASTMenu = uimenu(app.Lab89Menu);
            app.FASTMenu.MenuSelectedFcn = createCallbackFcn(app, @FASTMenuSelected, true);
            app.FASTMenu.Text = 'FAST';
            app.HarrisMenu = uimenu(app.Lab89Menu);
            app.HarrisMenu.MenuSelectedFcn = createCallbackFcn(app, @HarrisMenuSelected, true);
            app.HarrisMenu.Text = 'Harris';
            app.KAZEMenu = uimenu(app.Lab89Menu);
            app.KAZEMenu.MenuSelectedFcn = createCallbackFcn(app, @KAZEMenuSelected, true);
            app.KAZEMenu.Text = 'KAZE';
            app.MSERMenu = uimenu(app.Lab89Menu);
            app.MSERMenu.MenuSelectedFcn = createCallbackFcn(app, @MSERMenuSelected, true);
            app.MSERMenu.Text = 'MSER';
            app.Lab1011Menu = uimenu(app.UIFigure);
            app.Lab1011Menu.Text = 'Lab 10/11';
            app.KluczowanieMenu = uimenu(app.Lab1011Menu);
            app.KluczowanieMenu.MenuSelectedFcn = createCallbackFcn(app, @KluczowanieMenuSelected, true);
            app.KluczowanieMenu.Text = 'Kluczowanie';
            app.LiczbaskadowychLabel = uilabel(app.UIFigure);
            app.LiczbaskadowychLabel.Text = '';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = '';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = '';
            app.JasnoscLabel = uilabel(app.UIFigure);
            app.JasnoscLabel.Text = 'Jasnosc';
            app.SliderLigth = uislider(app.UIFigure);
            app.SliderLigth.Limits = [0 2];
            app.SliderLigth.MajorTicks = [];
            app.SliderLigth.MajorTickLabels = {''};
            app.SliderLigth.ValueChangedFcn = createCallbackFcn(app, @SliderLigthValueChanged, true);
            app.SliderLigth.ValueChangingFcn = createCallbackFcn(app, @SliderLigthValueChanging, true);
            app.SliderLigth.MinorTicks = [];
            app.SliderLigth.Value = 1;
            app.RedLabel = uilabel(app.UIFigure);
            app.RedLabel.Text = 'Red';
            app.SliderR = uislider(app.UIFigure);
            app.SliderR.Limits = [0 2];
            app.SliderR.MajorTicks = [];
            app.SliderR.ValueChangedFcn = createCallbackFcn(app, @SliderRValueChanged, true);
            app.SliderR.ValueChangingFcn = createCallbackFcn(app, @SliderRValueChanging, true);
            app.SliderR.MinorTicks = [];
            app.SliderR.Value = 1;
            app.GreenLabel = uilabel(app.UIFigure);
            app.GreenLabel.Text = {'Green'; ''};
            app.SliderG = uislider(app.UIFigure);
            app.SliderG.Limits = [0 2];
            app.SliderG.MajorTicks = [];
            app.SliderG.ValueChangedFcn = createCallbackFcn(app, @SliderGValueChanged, true);
            app.SliderG.ValueChangingFcn = createCallbackFcn(app, @SliderGValueChanging, true);
            app.SliderG.MinorTicks = [];
            app.SliderG.Value = 1;
            app.BlueLabel = uilabel(app.UIFigure);
            app.BlueLabel.Text = 'Blue';
            app.SliderB = uislider(app.UIFigure);
            app.SliderB.Limits = [0 2];
            app.SliderB.MajorTicks = [];
            app.SliderB.ValueChangedFcn = createCallbackFcn(app, @SliderBValueChanged, true);
            app.SliderB.ValueChangingFcn = createCallbackFcn(app, @SliderBValueChanging, true);
            app.SliderB.MinorTicks = [];
            app.SliderB.Value = 1;
            app.ImagefuseSliderLabel = uilabel(app.UIFigure);
            app.ImagefuseSliderLabel.Text = {'Image fuse'; ''};
            app.ImagefuseSlider = uislider(app.UIFigure);
            app.ImagefuseSlider.Limits = [0 1];
            app.ImagefuseSlider.MajorTicks = [];
            app.ImagefuseSlider.ValueChangingFcn = createCallbackFcn(app, @ImagefuseSliderValueChanging, true);
            app.ImagefuseSlider.MinorTicks = [];
            app.ImagefuseSlider.Value = 1;
            app.ProgowanieSliderLabel = uilabel(app.UIFigure);
            app.ProgowanieSliderLabel.Text = 'Progowanie';
            app.ProgowanieSlider = uislider(app.UIFigure);
            app.ProgowanieSlider.ValueChangingFcn = createCallbackFcn(app, @ProgowanieSliderValueChanging2, true);
            app.LiczbakrawedziLabel = uilabel(app.UIFigure);
            app.LiczbakrawedziLabel.Text = {'Liczba krawedzi:'; ''};
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxesR = uiaxes(app.UIFigure);
            app.UIAxesR.XTick = [];
            app.UIAxesR.YTick = [];
            app.UIAxesG = uiaxes(app.UIFigure);
            app.UIAxesG.XTick = [];
            app.UIAxesG.YTick = [];
            app.UIAxesB = uiaxes(app.UIFigure);
            app.UIAxesB.XTick = [];
            app.UIAxesB.YTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Obraz 1')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Obraz 2')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, {'Obraz koncowy'; ''})
            app.UIAxes5.XTick = [];
            app.UIAxes5.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Lab4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end